classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        LoadaphotoofyourskinchangeLabel  matlab.ui.control.Label
        ResultLabel      matlab.ui.control.Label
        AnalyseButton    matlab.ui.control.Button
        LoadimageButton  matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function ButtonLoad(app, event)
            global I
            [fName, dirName] = uigetfile('*.bmp;*.tif;*.jpg;*.tiff;*.png');
            jpg = fullfile(dirName, fName);
            zdj = imread(jpg);
            I = imresize(zdj, [224 224]);
            if(size(I, 3) == 1)
                I = cat(3, I, I, I);
                I = double(I);
            end
            imshow(I, 'Parent', app.UIAxes)
            
            set(app.AnalyseButton,'Enable','on');
            text = '';
            textLabel = sprintf('%s', text);
            app.Label.Text = textLabel;
            app.Label
        end

        % Button pushed function: AnalyseButton
        function ButtonAnalyse(app, event)
            global I
            plik = load('najlepszaNet.mat');
            plik = plik.net;
            YPred = classify(plik, I);
            YPred = double(YPred);

            x = (predict(plik, I)).*100;

            if(YPred == 1)
                x1 = x(1);
                text = sprintf('Probably your skin change is benign. Accuracy of this result is %.2f %%. To verify, go see a doctor.', x1);
                textLabel = sprintf('%s', text);
                app.Label.Text = textLabel;
                app.Label
            end
            if(YPred == 2)
                x2 = x(2);
                text = sprintf('Probably your skin change is malignant. Accuracy of this result is %.2f %%. To verify, go see a doctor.', x2);
                textLabel = sprintf('%s', text);
                app.Label.Text = textLabel;
                app.Label;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoad, true);
            app.LoadimageButton.Text = 'Load image';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonAnalyse, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.LoadaphotoofyourskinchangeLabel = uilabel(app.UIFigure);
            app.LoadaphotoofyourskinchangeLabel.Text = 'Load a photo of your skin change';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end